classdef AppTBB1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DmappingofthesamplethicknessfromToFSIMSimagesAppTBB1Label  matlab.ui.control.Label
        LoadingandsmoothingPanel        matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        Slider                          matlab.ui.control.Slider
        EnterSiimagenameEditFieldLabel  matlab.ui.control.Label
        EnterSiimagenameEditField       matlab.ui.control.EditField
        LoadtotalimageButton            matlab.ui.control.Button
        CropandloadButton               matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        CalculatorPanel                 matlab.ui.container.Panel
        PixelstocountsconversionPanel   matlab.ui.container.Panel
        XimagesizemmSpinnerLabel        matlab.ui.control.Label
        XimagesizemmSpinner             matlab.ui.control.Spinner
        YimagesizemmSpinnerLabel        matlab.ui.control.Label
        YimagesizemmSpinner             matlab.ui.control.Spinner
        CountspixelsfactorSpinnerLabel  matlab.ui.control.Label
        CountspixelsfactorSpinner       matlab.ui.control.Spinner
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        ThicknesscalibrationPanel       matlab.ui.container.Panel
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        PixelsrasterfactorEditFieldLabel  matlab.ui.control.Label
        PixelsrasterfactorEditField     matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        Tree                            matlab.ui.container.Tree
        InsertnewvaluesNode             matlab.ui.container.TreeNode
        ProteinsNode                    matlab.ui.container.TreeNode
        BradykininNode_2                matlab.ui.container.TreeNode
        LysosymeNode                    matlab.ui.container.TreeNode
        PolymersNode                    matlab.ui.container.TreeNode
        IrganoxNode                     matlab.ui.container.TreeNode
        TextArea_3                      matlab.ui.control.TextArea
        ShowcalibrationcurveButton      matlab.ui.control.Button
        anormalizedEditFieldLabel       matlab.ui.control.Label
        anormalizedEditField            matlab.ui.control.NumericEditField
        bnormalizedEditFieldLabel       matlab.ui.control.Label
        bnormalizedEditField            matlab.ui.control.NumericEditField
        Button_8                        matlab.ui.control.Button
        NormalizationwithtotalintensityPanel  matlab.ui.container.Panel
        CountspixelsfactorSpinner_2Label  matlab.ui.control.Label
        CountspixelsfactorSpinner_2     matlab.ui.control.Spinner
        LoadButton_2                    matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        Button_6                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        PlotgeneratorPanel              matlab.ui.container.Panel
        DmappreviewButton               matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        Generatefinal3DmapButton        matlab.ui.control.Button
        ColormapKnobLabel               matlab.ui.control.Label
        ColormapKnob                    matlab.ui.control.DiscreteKnob
        ApplythicknesscalibrationCheckBox  matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        Lamp_2                          matlab.ui.control.Lamp
        ApplynormalizationCheckBox      matlab.ui.control.CheckBox
        Button_9                        matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        Data
        smoothedData % Description
        Xlength % Description
        Ylength % Description
        final_x % Description
        final_y % Description
        filenameSi % Description
        calibration_factor % Description
        smoothedData2 % Description
        heightData % Description
        filenameTot % Description
        DataTot % Description
        normalizedData % Description
        rect % Description
        croptag % Description
        smoothedtag % Description
        imageNoCrop % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.Lamp.Color = 'r';                                           % Begin with a red lamps
            app.Lamp_2.Color = 'r'; 
        end

        % Button pushed function: LoadtotalimageButton
        function LoadtotalimageButtonPushed(app, event)
            app.croptag = 0;
            t = Tiff(app.filenameSi,'r');
            app.Data = read(t);                                             % load tif image in uint8 (only integrer from 0 to 255)
            app.Data = im2double(app.Data).*255;                            % convert uint8 in matrix between [0 1]
            s=surf(app.UIAxes,app.Data);
            s.EdgeColor = 'none';                                           % To avoid black plot due to the high number of points
            %daspect(app.UIAxes,[1 1 1]) 
            zlabel(app.UIAxes, 'Pixel intensity')
            title(app.UIAxes,{'\fontsize{16}Intensity image'; app.filenameSi})
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.smoothedtag = 1;
            changingValue = event.Value;
            app.smoothedData = smoothn(app.Data,changingValue);
            s=surf(app.UIAxes,app.smoothedData);
            s.EdgeColor = 'none';
            title(app.UIAxes,{'\fontsize{16}Intensity image'; app.filenameSi})
        end

        % Callback function: CountspixelsfactorSpinner, 
        % CountspixelsfactorSpinner_2, DmappreviewButton, 
        % PixelsrasterfactorEditField, aEditField, bEditField
        function FinalplotpreviewButtonPushed(app, event)
            [x,y] = size(app.Data);
            new_x = linspace(0,app.Xlength,x);
            new_y = linspace(0,app.Ylength,y);
            %new_x = atan(new_x/5.5)*180/pi;
            %uncomment to transform axis in degree (depend of the geometry)
            assignin('base','Xlength',app.Xlength);
            assignin('base','Ylength',app.Ylength);
            [app.final_x,app.final_y] = meshgrid(new_y,new_x);
            Counts_pixel = app.CountspixelsfactorSpinner.Value;
            Counts_pixel_2 = app.CountspixelsfactorSpinner_2.Value;
            Pixel_raster = app.PixelsrasterfactorEditField.Value;
            if app.smoothedtag == 1
                app.smoothedData2 = app.smoothedData*Counts_pixel*Pixel_raster;
            else
                app.smoothedData2 = app.Data*Counts_pixel*Pixel_raster;
            end
           
            assignin('base','smoothedData2',app.smoothedData2);
            assignin('base','final_x',app.final_x);
            assignin('base','final_y',app.final_y);
            
            if app.ApplythicknesscalibrationCheckBox.Value == 0                
                if app.ApplynormalizationCheckBox.Value == 0                % not calibrated not normalized
                    s=surf(app.UIAxes2, app.final_x,app.final_y, app.smoothedData2);
                    s.EdgeColor = 'none';
                    zlabel(app.UIAxes2, 'counts')
                    title(app.UIAxes2,{'\fontsize{16}Counts (not normalized)'; app.filenameSi})
                elseif app.ApplynormalizationCheckBox.Value == 1            % not calibrated normalized
                    app.DataTot = app.DataTot*Counts_pixel_2;
                    app.normalizedData = app.smoothedData2/Pixel_raster./app.DataTot;
                    s=surf(app.UIAxes2, app.final_x,app.final_y, app.normalizedData);
                    s.EdgeColor = 'none';
                    zlabel(app.UIAxes2, 'counts')
                    title(app.UIAxes2,{'\fontsize{16}Counts (normalized)'; app.filenameSi})
                end
            elseif app.ApplythicknesscalibrationCheckBox.Value == 1
                if app.ApplynormalizationCheckBox.Value == 0                % calibrated not normalized
                    % eliminate 0 or negative values due to the smoothing
                    app.smoothedData2 = max(app.smoothedData2,0.001);
                    % equation of calibration
                    a = app.aEditField.Value;
                    b = app.bEditField.Value;
                    app.heightData = log(app.smoothedData2/(a))/b;
                    app.heightData = max(app.heightData,0);                 % replace negative thickness by 0
                    assignin('base','heightData',app.heightData);
                    s=surf(app.UIAxes2, app.final_x,app.final_y, app.heightData);
                    s.EdgeColor = 'none';
                    zlabel(app.UIAxes2, 'Height (nm)')
                    title(app.UIAxes2,{'\fontsize{16}Thickness 3D image (not normalized)'; app.filenameSi})
                elseif app.ApplynormalizationCheckBox.Value == 1            % calibrated normalized
                    % eliminate 0 or negative values due to the smoothing
                    app.DataTot = app.DataTot*Counts_pixel_2;                    app.normalizedData = app.smoothedData2/Pixel_raster./app.DataTot;
                    app.normalizedData = max(app.normalizedData,0.0000001);
                    % equation of calibration
                    a = app.anormalizedEditField.Value;
                    b = app.bnormalizedEditField.Value;
                    app.heightData = log(app.normalizedData/(a))/b; 
                    app.heightData = max(app.heightData,0);
                    assignin('base','heightData',app.heightData);
                    s=surf(app.UIAxes2, app.final_x,app.final_y, app.heightData);
                    s.EdgeColor = 'none';
                    zlabel(app.UIAxes2, 'Thickness (nm)')
                    title(app.UIAxes2,{'\fontsize{16}Thickness 3D image (normalized)'; app.filenameSi})
                end
                
            end
            
           
        end

        % Value changing function: XimagesizemmSpinner
        function XimagesizemmSpinnerValueChanging(app, event)
            t = Tiff(app.filenameSi,'r');
            app.imageNoCrop = read(t); 
            [x,~] = size(app.imageNoCrop);
            assignin('base','Data',app.imageNoCrop);
            assignin('base','x',x);
            if app.croptag == 0
                app.Xlength = event.Value;
            elseif app.croptag == 1
                app.Xlength = event.Value*app.rect(4)/x;
            end
            
        end

        % Value changing function: YimagesizemmSpinner
        function YimagesizemmSpinnerValueChanging(app, event)
 
            [~,y] = size(app.imageNoCrop);
            if app.croptag == 0
                app.Ylength = event.Value;
            elseif app.croptag == 1
                app.Ylength = event.Value*app.rect(3)/y;
            end
            
        end

        % Button pushed function: Generatefinal3DmapButton
        function Generatefinal3DmapButtonPushed(app, event)

            figure
            if strcmp(app.ColormapKnob.Value,'default')
               colormap default
            elseif strcmp(app.ColormapKnob.Value,'jet')
               colormap jet
            elseif strcmp(app.ColormapKnob.Value,'spring')
               colormap spring
            elseif strcmp(app.ColormapKnob.Value,'summer')
               colormap summer
            end
            
                
             if app.ApplythicknesscalibrationCheckBox.Value == 0
                 if app.ApplynormalizationCheckBox.Value == 0  
                    s=surf(app.final_x,app.final_y, app.smoothedData2);
                    s.EdgeColor = 'none';
                    title({'\fontsize{16}counts image (not normalized)'; app.filenameSi})
                    xlabel('mm')
                    ylabel('mm')
                    zlabel('counts')
                    colorbar
                 elseif app.ApplynormalizationCheckBox.Value == 1 
                    s=surf(app.final_x,app.final_y, app.normalizedData);
                    s.EdgeColor = 'none';
                    title({'\fontsize{16}counts image (normalized)'; app.filenameSi})
                    xlabel('mm')
                    ylabel('mm')
                    zlabel('counts')
                    colorbar
                 end

            elseif app.ApplythicknesscalibrationCheckBox.Value == 1
                if app.ApplynormalizationCheckBox.Value == 0  
                    s=surf(app.final_x,app.final_y, app.heightData);
                    s.EdgeColor = 'none';
                    title({'\fontsize{16}Thickness 3D image (not normalized)'; app.filenameSi})
                    xlabel('mm')
                    ylabel('mm')
                    zlabel('Height (nm)')
                    colorbar
                elseif app.ApplynormalizationCheckBox.Value == 1 
                    s=surf(app.final_x,app.final_y, app.heightData);
                    s.EdgeColor = 'none';
                    title({'\fontsize{16}Thickness 3D image (normalized)'; app.filenameSi})
                    xlabel('mm')
                    ylabel('mm')
                    zlabel('Height (nm)')
                    colorbar
                end
                
            end
            
        end

        % Value changing function: EnterSiimagenameEditField
        function EnterSiimagenameEditFieldValueChanging(app, event)
            app.filenameSi = event.Value;
            
        end

        % Callback function
        function CountspixelsfactorSpinnerValueChanging(app, event)
            app.calibration_factor = event.Value;
            
        end

        % Value changed function: ApplythicknesscalibrationCheckBox
        function ApplythicknesscalibrationCheckBoxValueChanged(app, event)
            if app.ApplythicknesscalibrationCheckBox.Value == 0
                app.Lamp.Color = 'r';
            elseif app.ApplythicknesscalibrationCheckBox.Value == 1
                app.Lamp.Color = 'g';
            end
        end

        % Value changed function: ColormapKnob
        function ColormapKnobValueChanged(app, event)
            value = app.ColormapKnob.Value;
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % Set the calibration parameters in function of the molecule
            % selected in the molecule library
            if strcmp(app.Tree.SelectedNodes.Text,'Insert new values')
                f = msgbox('Insert coefficient values in the boxes','information');
                app.aEditField.Value = 0;
                app.bEditField.Value = 0;
                app.anormalizedEditField.Value = 0;
                app.bnormalizedEditField.Value = 0;
            end
            if strcmp(app.Tree.SelectedNodes.Text,'Lysosyme')
                app.aEditField.Value = 2000000;
                app.bEditField.Value = -0.998;
                app.anormalizedEditField.Value = 0.1139;
                app.bnormalizedEditField.Value = -0.8548;
            end
            if strcmp(app.Tree.SelectedNodes.Text,'Bradykinin')
                app.aEditField.Value = 2500000;
                app.bEditField.Value = -1;
                app.anormalizedEditField.Value = 1;
                app.bnormalizedEditField.Value = 1;
            end
            if strcmp(app.Tree.SelectedNodes.Text,'Irganox')
                errordlg('No calibration yet','File not found');
            end
                     
        end

        % Button pushed function: ShowcalibrationcurveButton
        function ShowcalibrationcurveButtonPushed(app, event)
            % This function aimed to display the calibration curve of the
            % molecule selected in the molecules library
            if strcmp(app.Tree.SelectedNodes.Text,'Lysosyme')
                X = xlsread('calibration library.xlsx','A3:A41');
                Y = xlsread('calibration library.xlsx','B3:B41');
                figure
                g = gcf; % Get handle to the current (last) figure
                g.WindowState = 'maximized';
                [f,gof] = fit(X,Y,'exp1','Robust','On');                    % Compute exponential fit of the calibration curve see fitoption for method details
                rsquare = gof.rsquare;
                assignin('base','gof',gof)                                  % Show fit properties in the ommand window
                assignin('base','f',f)
                
                subplot(1,2,1)
                plot(f,X,Y)
                eq_str = sprintf('\n Counts = a.exp(b.height)) \n a = %.5f \n b = %.5f', f.a, f.b);
                dim = [.25 .55 .15 .15];                                      % Dimension of the Characteristic box on the plot [x y xsize ysize]
                str = ['Rsquare =', num2str(rsquare), eq_str];
                newStr = join(str);
                annotation('textbox',dim,'String',newStr)                   % Characteristic box on the plot
                %set(gca, 'YScale', 'log')                                  % Y axis in log scale
                title('Lysosyme calibration (linear scale)')
                xlabel('Thickness (nm)')
                ylabel('Normalyzed Si intensity')
                subplot(1,2,2)
                plot(f,X,Y)
                set(gca, 'YScale', 'log') 
                title('Lysosyme calibration (log scale)')
                xlabel('Thickness (nm)')
                ylabel('Normalyzed Si intensity')
            end
            if strcmp(app.Tree.SelectedNodes.Text,'Bradykinin')
                figure
            end
            if strcmp(app.Tree.SelectedNodes.Text,'PET')
                errordlg('No calibration yet','File not found');
            end
        end

        % Value changing function: FilenameEditField
        function FilenameEditFieldValueChanging(app, event)
            app.filenameTot = event.Value;
            
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)

            if app.croptag == 1
                t = Tiff(app.filenameTot,'r');
                app.DataTot = read(t);                                             % load tif image in uint8 (only integrer from 0 to 255)
                app.DataTot = imcrop(app.DataTot,app.rect);
                app.DataTot = im2double(app.DataTot).*255;
            elseif app.croptag == 0
                t = Tiff(app.filenameTot,'r');
                app.DataTot = read(t);                                             % load tif image in uint8 (only integrer from 0 to 255)
                app.DataTot = im2double(app.DataTot).*255; 
            end

        end

        % Value changed function: ApplynormalizationCheckBox
        function ApplynormalizationCheckBoxValueChanged(app, event)
            if app.ApplynormalizationCheckBox.Value == 0
                app.Lamp_2.Color = 'r';
            elseif app.ApplynormalizationCheckBox.Value == 1
                app.Lamp_2.Color = 'g';
            end
        end

        % Button pushed function: CropandloadButton
        function CropandloadButtonPushed(app, event)
            app.croptag = 1;
            t = Tiff(app.filenameSi,'r');
            app.Data = read(t);                                             % load tif image in uint8 (only integrer from 0 to 255)
            [app.Data,app.rect] = imcrop(app.Data);                         % crop the image and save position and dimension of the crop rectangle in app.rect
            assignin('base','rect',app.rect);
            close
            app.Data = im2double(app.Data).*255;                            % convert uint8 in matrix between [0 1]
            s=surf(app.UIAxes,app.Data);
            s.EdgeColor = 'none';                                           % To avoid black plot due to the high number of points
            %daspect(app.UIAxes,[1 1 1]) 
            zlabel(app.UIAxes, 'Pixel intensity')
            title(app.UIAxes,{'\fontsize{16}Intensity image'; app.filenameSi})
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            f = msgbox('Load the total .tif image of Si^+ signal (exported from SurfaceLab).','information');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            f = msgbox('Crop and load the total .tif image of Si^+ signal (exported from SurfaceLab).','information');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            f = msgbox('Enter the X size of the total image in mm. If you crop the image the new size will be calculated from the total size. ','information');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            f = msgbox('Enter the Y size of the total image in mm. If you crop the image the new size will be calculated from the total size. ','information');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            f = msgbox('This factor is used to convert the pixel intensity of the Si image in counts. For that, you need to divide the maximun intensity in counts (e.g. Max = 33 counts) by the intensity max of the 8-bit image (e.i. 255). ','information');
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            f = msgbox('Load the total .tif image of the total intensity signal (exported from SurfaceLab). The Si image can be normalise by the total image in order to be independant of the beam current intnesity.','information');
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            f = msgbox('This factor is used to convert the pixel intensity of the total image in counts. For that, you need to divide the maximun intensity in counts (e.g. Max = 8000 counts) by the intensity max of the 8-bit image (e.i. 255).','information');
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            f = msgbox('This factor is the pixel size of the raster used for the calibration (128x128=16384). This factor is applied because the calibration was done with a specific raster (therefore with an intensity 16384 times higher in this case). If the calibration is done with pixels intensities this factor should be equal to one','information');
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            f = msgbox('Apply thickness calibration (green light) means that we convert the Si counts into a thickness in nm. For that the calibration coefficients are used.','information');
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            f = msgbox('Apply the normalization (green light) means that we divide each values of the Si image by the total intensity corresponding to this value. It allows to be independant of the analysis current.','information');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DmappingofthesamplethicknessfromToFSIMSimagesAppTBB1Label = uilabel(app.UIFigure);
            app.DmappingofthesamplethicknessfromToFSIMSimagesAppTBB1Label.Text = '3D mapping of the sample thickness from ToF-SIMS images (AppTBB1)';
            app.LoadingandsmoothingPanel = uipanel(app.UIFigure);
            app.LoadingandsmoothingPanel.Title = 'Loading and smoothing';
            app.UIAxes = uiaxes(app.LoadingandsmoothingPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Pixels')
            ylabel(app.UIAxes, 'Pixels')
            app.Slider = uislider(app.LoadingandsmoothingPanel);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EnterSiimagenameEditFieldLabel = uilabel(app.LoadingandsmoothingPanel);
            app.EnterSiimagenameEditFieldLabel.Text = 'Enter Si image name:';
            app.EnterSiimagenameEditField = uieditfield(app.LoadingandsmoothingPanel, 'text');
            app.EnterSiimagenameEditField.ValueChangingFcn = createCallbackFcn(app, @EnterSiimagenameEditFieldValueChanging, true);
            app.EnterSiimagenameEditField.Value = '.tif';
            app.LoadtotalimageButton = uibutton(app.LoadingandsmoothingPanel, 'push');
            app.LoadtotalimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtotalimageButtonPushed, true);
            app.LoadtotalimageButton.Text = 'Load total image';
            app.CropandloadButton = uibutton(app.LoadingandsmoothingPanel, 'push');
            app.CropandloadButton.ButtonPushedFcn = createCallbackFcn(app, @CropandloadButtonPushed, true);
            app.CropandloadButton.Text = 'Crop and load';
            app.Button = uibutton(app.LoadingandsmoothingPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.LoadingandsmoothingPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            app.CalculatorPanel = uipanel(app.UIFigure);
            app.CalculatorPanel.Title = 'Calculator';
            app.PixelstocountsconversionPanel = uipanel(app.CalculatorPanel);
            app.PixelstocountsconversionPanel.Title = 'Pixels to counts conversion';
            app.XimagesizemmSpinnerLabel = uilabel(app.PixelstocountsconversionPanel);
            app.XimagesizemmSpinnerLabel.Text = 'X image size (mm)';
            app.XimagesizemmSpinner = uispinner(app.PixelstocountsconversionPanel);
            app.XimagesizemmSpinner.ValueChangingFcn = createCallbackFcn(app, @XimagesizemmSpinnerValueChanging, true);
            app.YimagesizemmSpinnerLabel = uilabel(app.PixelstocountsconversionPanel);
            app.YimagesizemmSpinnerLabel.Text = 'Y  image size(mm)';
            app.YimagesizemmSpinner = uispinner(app.PixelstocountsconversionPanel);
            app.YimagesizemmSpinner.ValueChangingFcn = createCallbackFcn(app, @YimagesizemmSpinnerValueChanging, true);
            app.CountspixelsfactorSpinnerLabel = uilabel(app.PixelstocountsconversionPanel);
            app.CountspixelsfactorSpinnerLabel.Text = 'Counts/pixels factor';
            app.CountspixelsfactorSpinner = uispinner(app.PixelstocountsconversionPanel);
            app.CountspixelsfactorSpinner.ValueChangingFcn = createCallbackFcn(app, @FinalplotpreviewButtonPushed, true);
            app.CountspixelsfactorSpinner.Value = 1;
            app.Button_3 = uibutton(app.PixelstocountsconversionPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '?';
            app.Button_4 = uibutton(app.PixelstocountsconversionPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '?';
            app.Button_5 = uibutton(app.PixelstocountsconversionPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '?';
            app.ThicknesscalibrationPanel = uipanel(app.CalculatorPanel);
            app.ThicknesscalibrationPanel.Title = 'Thickness calibration';
            app.aEditFieldLabel = uilabel(app.ThicknesscalibrationPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.ThicknesscalibrationPanel, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @FinalplotpreviewButtonPushed, true);
            app.aEditField.Value = 2000000;
            app.bEditFieldLabel = uilabel(app.ThicknesscalibrationPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ThicknesscalibrationPanel, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @FinalplotpreviewButtonPushed, true);
            app.bEditField.Value = -0.998;
            app.PixelsrasterfactorEditFieldLabel = uilabel(app.ThicknesscalibrationPanel);
            app.PixelsrasterfactorEditFieldLabel.Text = 'Pixels/raster factor';
            app.PixelsrasterfactorEditField = uieditfield(app.ThicknesscalibrationPanel, 'numeric');
            app.PixelsrasterfactorEditField.ValueChangedFcn = createCallbackFcn(app, @FinalplotpreviewButtonPushed, true);
            app.PixelsrasterfactorEditField.Value = 16384;
            app.TextArea = uitextarea(app.ThicknesscalibrationPanel);
            app.TextArea.Value = {'Counts = a.exp(-b.Thickness)'};
            app.Tree = uitree(app.ThicknesscalibrationPanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.InsertnewvaluesNode = uitreenode(app.Tree);
            app.InsertnewvaluesNode.Text = 'Insert new values';
            app.ProteinsNode = uitreenode(app.Tree);
            app.ProteinsNode.Icon = 'protein.png';
            app.ProteinsNode.Text = 'Proteins';
            app.BradykininNode_2 = uitreenode(app.ProteinsNode);
            app.BradykininNode_2.Text = 'Bradykinin';
            app.LysosymeNode = uitreenode(app.ProteinsNode);
            app.LysosymeNode.Text = 'Lysosyme';
            app.PolymersNode = uitreenode(app.Tree);
            app.PolymersNode.Icon = 'polymer.png';
            app.PolymersNode.Text = 'Polymers';
            app.IrganoxNode = uitreenode(app.PolymersNode);
            app.IrganoxNode.Text = 'Irganox';
            app.TextArea_3 = uitextarea(app.ThicknesscalibrationPanel);
            app.TextArea_3.Value = {'Molecules library'};
            app.ShowcalibrationcurveButton = uibutton(app.ThicknesscalibrationPanel, 'push');
            app.ShowcalibrationcurveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowcalibrationcurveButtonPushed, true);
            app.ShowcalibrationcurveButton.Text = 'Show calibration curve';
            app.anormalizedEditFieldLabel = uilabel(app.ThicknesscalibrationPanel);
            app.anormalizedEditFieldLabel.Text = 'a normalized';
            app.anormalizedEditField = uieditfield(app.ThicknesscalibrationPanel, 'numeric');
            app.anormalizedEditField.Value = 0.13732;
            app.bnormalizedEditFieldLabel = uilabel(app.ThicknesscalibrationPanel);
            app.bnormalizedEditFieldLabel.Text = 'b normalized';
            app.bnormalizedEditField = uieditfield(app.ThicknesscalibrationPanel, 'numeric');
            app.bnormalizedEditField.Value = -0.99986;
            app.Button_8 = uibutton(app.ThicknesscalibrationPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '?';
            app.NormalizationwithtotalintensityPanel = uipanel(app.CalculatorPanel);
            app.NormalizationwithtotalintensityPanel.Title = 'Normalization with total intensity';
            app.CountspixelsfactorSpinner_2Label = uilabel(app.NormalizationwithtotalintensityPanel);
            app.CountspixelsfactorSpinner_2Label.Text = 'Counts/pixels factor';
            app.CountspixelsfactorSpinner_2 = uispinner(app.NormalizationwithtotalintensityPanel);
            app.CountspixelsfactorSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FinalplotpreviewButtonPushed, true);
            app.CountspixelsfactorSpinner_2.Value = 1;
            app.LoadButton_2 = uibutton(app.NormalizationwithtotalintensityPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.FilenameLabel = uilabel(app.NormalizationwithtotalintensityPanel);
            app.FilenameLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.NormalizationwithtotalintensityPanel, 'text');
            app.FilenameEditField.ValueChangingFcn = createCallbackFcn(app, @FilenameEditFieldValueChanging, true);
            app.FilenameEditField.Value = '.tif';
            app.Button_6 = uibutton(app.NormalizationwithtotalintensityPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '?';
            app.Button_7 = uibutton(app.NormalizationwithtotalintensityPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '?';
            app.PlotgeneratorPanel = uipanel(app.UIFigure);
            app.PlotgeneratorPanel.Title = 'Plot generator';
            app.DmappreviewButton = uibutton(app.PlotgeneratorPanel, 'push');
            app.DmappreviewButton.ButtonPushedFcn = createCallbackFcn(app, @FinalplotpreviewButtonPushed, true);
            app.DmappreviewButton.Text = '3D map preview:';
            app.UIAxes2 = uiaxes(app.PlotgeneratorPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'mm')
            ylabel(app.UIAxes2, 'mm')
            app.Generatefinal3DmapButton = uibutton(app.PlotgeneratorPanel, 'push');
            app.Generatefinal3DmapButton.ButtonPushedFcn = createCallbackFcn(app, @Generatefinal3DmapButtonPushed, true);
            app.Generatefinal3DmapButton.Text = 'Generate final 3D map:';
            app.ColormapKnobLabel = uilabel(app.PlotgeneratorPanel);
            app.ColormapKnobLabel.Text = 'Colormap';
            app.ColormapKnob = uiknob(app.PlotgeneratorPanel, 'discrete');
            app.ColormapKnob.Items = {'default', 'jet', 'spring', 'summer'};
            app.ColormapKnob.ValueChangedFcn = createCallbackFcn(app, @ColormapKnobValueChanged, true);
            app.ColormapKnob.Value = 'default';
            app.ApplythicknesscalibrationCheckBox = uicheckbox(app.PlotgeneratorPanel);
            app.ApplythicknesscalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplythicknesscalibrationCheckBoxValueChanged, true);
            app.ApplythicknesscalibrationCheckBox.Text = 'Apply thickness calibration';
            app.Lamp = uilamp(app.PlotgeneratorPanel);
            app.Lamp_2 = uilamp(app.PlotgeneratorPanel);
            app.ApplynormalizationCheckBox = uicheckbox(app.PlotgeneratorPanel);
            app.ApplynormalizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplynormalizationCheckBoxValueChanged, true);
            app.ApplynormalizationCheckBox.Text = 'Apply normalization';
            app.Button_9 = uibutton(app.PlotgeneratorPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '?';
            app.Button_10 = uibutton(app.PlotgeneratorPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTBB1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end